classdef udp_data_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        backButton      matlab.ui.control.Button
        spinnerImage    matlab.ui.control.Image
        httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel  matlab.ui.control.Label
        settingsButton  matlab.ui.control.Button
        seedataButton   matlab.ui.control.Button
        listeningforvehicleudpsensorfeedLabel  matlab.ui.control.Label
        Image           matlab.ui.control.Image
        accAxes         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        u % not type
        packetSize
        time
        yaw
        roll
        pitch
        ax
        ay
        az
        rollLine
        pitchLine
        yawLine
        axLine
        ayLine
        azLine
        switchToData
        my_timer
        lastPacketTime
        lost_connection
        plot_colors
        line_width
        justSwitched
        ringBuffer
        bufferIdx
        bufferLength
        buffer_full
    end

    methods (Access = private)
        function start_udp_monitor(app)
            app.switchToData = false;
            app.packetSize = 28;
            app.lost_connection = true;
            app.plot_colors = [
                79 62 140;    % #4F3E8C
                73 191 191;    % #49BFBF
                242 193 46     % #F2C12E
                ] / 255;
            app.line_width = 2.1;

            pause(0.25)
            app.listeningforvehicleudpsensorfeedLabel.Position = [1112 497 322 26];
            pause(0.5)
            app.spinnerImage.Visible = 'on';

            app.bufferLength = 3;
            app.ringBuffer = zeros(app.bufferLength, 7);
            app.bufferIdx = 1;

            app.u = udpportfind;
            if isempty(app.u)
                localPort = 5005;
                app.u = udpport("LocalPort", localPort);
            else
                app.u = app.u(1); % Nimm ersten gefundenen Port
            end

            % Callback auf eingehende Daten
            configureCallback(app.u, "byte", 1, @(src, evt) on_udp_data(app, src, evt));

            app.lastPacketTime = datetime('now');
            app.my_timer = timer('Period',1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.my_timer.TimerFcn = @(x,y) lost_udp_connection(app,x,y);
            start(app.my_timer);
        end

        function on_udp_data(app, src, evt)
            app.lastPacketTime = datetime('now');
            app.listeningforvehicleudpsensorfeedLabel.Text = "receving udp packages";
            app.seedataButton.Visible = 'on';
            app.lost_connection = false;
            
            raw = read(src, app.packetSize, "uint8");
            if numel(raw) < app.packetSize
                disp("Incomplete UDP packet");
                return
            end
            vals = typecast(uint8(raw), 'single'); % 1x7

            app.ringBuffer(app.bufferIdx, :) = vals;
            if app.bufferIdx >= 3
                app.buffer_full = true;
                app.bufferIdx = 1;
            else
                app.buffer_full = false;
                app.bufferIdx = app.bufferIdx + 1;
            end

            if app.switchToData
                app.justSwitched = false;
                app.Image.Visible = 'off';
                app.UIAxes.Visible = 'on';
                app.accAxes.Visible = 'on';
                app.backButton.Visible = 'on';
                app.listeningforvehicleudpsensorfeedLabel.Visible = "off";
                app.settingsButton.Visible = 'off';
                app.seedataButton.Visible = 'off';
                app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel.Visible = "off";
                app.spinnerImage.Visible = 'off';

                if app.buffer_full

                    idx = [app.bufferIdx:app.bufferLength 1:app.bufferIdx-1];
                    app.time  = [app.time; app.ringBuffer(idx,1)];
                    app.roll  = [app.roll; app.ringBuffer(idx,2)];
                    app.pitch = app.ringBuffer(idx,3);
                    app.yaw   = app.ringBuffer(idx,4);
                    app.ax    = app.ringBuffer(idx,5);
                    app.ay    = app.ringBuffer(idx,6);
                    app.az    = app.ringBuffer(idx,7);

                    if ~isempty(app.time)
                        if isempty(app.rollLine)
                            app.redraw_lines();
                        else
                            app.rollLine.XData  = app.time;  app.rollLine.YData  = app.roll;
                            % app.pitchLine.XData = app.time;  app.pitchLine.YData = app.pitch;
                            % app.yawLine.XData   = app.time;  app.yawLine.YData   = app.yaw;
                            % app.axLine.XData    = app.time;  app.axLine.YData    = app.ax;
                            % app.ayLine.XData    = app.time;  app.ayLine.YData    = app.ay;
                            % app.azLine.XData    = app.time;  app.azLine.YData    = app.az;
                        end
                    end

                    drawnow limitrate;
                end
            end
        end

        function clear_lines(app)
            delete(app.rollLine); app.rollLine = [];
            delete(app.pitchLine); app.pitchLine = [];
            delete(app.yawLine); app.yawLine = [];

            %delete(app.UIAxes);

            delete(app.axLine); app.axLine = [];
            delete(app.ayLine); app.ayLine = [];
            delete(app.azLine); app.azLine = [];

            %delete(app.accAxes);

            cla(app.UIAxes,'reset');
            cla(app.accAxes, 'reset');

            hold(app.UIAxes,'off');
            hold(app.accAxes,'off');

            app.time  = [];
            app.roll  = [];
            app.pitch = [];
            app.yaw   = [];
            app.ax    = [];
            app.ay    = [];
            app.az   = [];
        end

        function redraw_lines(app)
            app.rollLine  = plot(app.UIAxes, app.time, app.roll,  'Color', app.plot_colors(1,:), 'LineWidth', app.line_width); hold(app.UIAxes,'on');
            app.pitchLine = plot(app.UIAxes, app.time, app.pitch, 'Color', app.plot_colors(2,:), 'LineWidth', app.line_width);
            app.yawLine   = plot(app.UIAxes, app.time, app.yaw,   'Color', app.plot_colors(3,:), 'LineWidth', app.line_width);
            legend(app.UIAxes, {'Roll','Pitch','Yaw'},  'Location', 'northeastoutside','Box','off');
            hold(app.UIAxes,'off');

            app.axLine = plot(app.accAxes, app.time, app.ax, 'Color', app.plot_colors(2,:), 'LineWidth', app.line_width); hold(app.accAxes,'on');
            app.ayLine = plot(app.accAxes, app.time, app.ay, 'Color', app.plot_colors(1,:), 'LineWidth', app.line_width);
            app.azLine   = plot(app.accAxes, app.time, app.az, 'Color', app.plot_colors(3,:), 'LineWidth', app.line_width);
            legend(app.accAxes, {'ax','ay','az'}, 'Location', 'northeastoutside', 'Box','off');
            hold(app.accAxes,'off');

            grid(app.UIAxes, 'on');
            grid(app.accAxes, 'on');
            xlabel(app.accAxes, 'time in s');
            ylabel(app.UIAxes, 'angle in deg');
            ylabel(app.accAxes, 'acceleration in m/s^2');
            title(app.UIAxes, 'UDP Live Data');
            axis(app.UIAxes, 'auto');
        end

        function start_animation(app)
            app.UIAxes.Visible = 'off';
            app.accAxes.Visible = 'off';
            app.backButton.Visible = 'off';

            app.Image.Visible = 'off';
            app.listeningforvehicleudpsensorfeedLabel.Visible = "off";
            app.settingsButton.Visible = 'off';
            app.seedataButton.Visible = 'off';
            app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel.Visible = "off";
            app.spinnerImage.Visible = 'off';

            pause(1)
            app.Image.Visible = 'on';
            app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel.Visible = "on";
            pause(0.25)
            app.listeningforvehicleudpsensorfeedLabel.Position = [1062 497 322 26];
            app.listeningforvehicleudpsensorfeedLabel.Visible = "on";
            pause(0.25)
            app.settingsButton.Visible = 'on';
        end

        function lost_udp_connection(app,x,y)
            no_connection_timer = datetime('now') - app.lastPacketTime;
            secondsElapsed = seconds(no_connection_timer);
            if ~app.lost_connection && secondsElapsed >= 2
                app.lost_connection = true;
                app.switchToData = false;

                app.clear_lines();

                app.listeningforvehicleudpsensorfeedLabel.Text = "listening for vehicle udp sensor feed";
                app.UIAxes.Visible = 'off';
                app.accAxes.Visible = 'off';
                app.Image.Visible = 'on';
                app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel.Visible = "on";
                app.listeningforvehicleudpsensorfeedLabel.Visible = "on";
                app.settingsButton.Visible = 'on';
                app.seedataButton.Visible = 'off';
                app.spinnerImage.Visible = 'on';
                app.backButton.Visible = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            start_animation(app)
            start_udp_monitor(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.my_timer) && isvalid(app.my_timer)
                stop(app.my_timer);
                delete(app.my_timer);
            end

            if ~isempty(app.u)
                clear app.u;
            end

            delete(app)
        end

        % Button pushed function: seedataButton
        function see_data_button(app, event)
            app.switchToData = true;
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)
            app.listeningforvehicleudpsensorfeedLabel.Visible = "off";
            app.settingsButton.Visible = 'off';
            app.seedataButton.Visible = 'off';
            app.onlocalhost1921682210withlocalport5005Label.Visible = "off";
            app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel.Visible = "off";
            app.Image.Visible = 'off';
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            app.switchToData = false;

            app.clear_lines();

            % Flush the UDP buffer
            if ~isempty(app.u)
                flush(app.u);  % Discards any pending data
            end

            app.UIAxes.Visible = 'off';
            app.accAxes.Visible = 'off';
            app.Image.Visible = 'on';
            app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel.Visible = "on";
            app.listeningforvehicleudpsensorfeedLabel.Visible = "on";
            app.settingsButton.Visible = 'on';
            app.seedataButton.Visible = 'on';
            app.spinnerImage.Visible = 'on';
            app.backButton.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.accAxes = uiaxes(app.UIFigure);
            title(app.accAxes, 'Title')
            xlabel(app.accAxes, 'X')
            ylabel(app.accAxes, 'Y')
            zlabel(app.accAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'vehicle.png');
            app.listeningforvehicleudpsensorfeedLabel = uilabel(app.UIFigure);
            app.listeningforvehicleudpsensorfeedLabel.Text = 'listening for vehicle udp sensor feed...';
            app.seedataButton = uibutton(app.UIFigure, 'push');
            app.seedataButton.ButtonPushedFcn = createCallbackFcn(app, @see_data_button, true);
            app.seedataButton.Text = 'see data';
            app.settingsButton = uibutton(app.UIFigure, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Icon = fullfile(pathToMLAPP, 'images', 'icons8-settings-500.png');
            app.settingsButton.Text = '';
            app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel = uilabel(app.UIFigure);
            app.httpssketchfabcom3dmodelsbmwm4widebodywwwvecarzcoma5687befab084efdba9a5c99a9a8ad4cLabel.Text = 'https://sketchfab.com/3d-models/bmw-m4-widebody-wwwvecarzcom-a5687befab084efdba9a5c99a9a8ad4c';
            app.spinnerImage = uiimage(app.UIFigure);
            app.spinnerImage.ImageSource = fullfile(pathToMLAPP, 'images', 'spinner.gif');
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = 'back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = udp_data_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end